classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Joint5Slider                    matlab.ui.control.Slider
        Joint5SliderLabel               matlab.ui.control.Label
        Joint6Slider                    matlab.ui.control.Slider
        Joint6SliderLabel               matlab.ui.control.Label
        Joint5Slider_2                  matlab.ui.control.Slider
        Joint5Slider_2Label             matlab.ui.control.Label
        Joint4Slider_2                  matlab.ui.control.Slider
        Joint4Slider_2Label             matlab.ui.control.Label
        Joint3Slider_2                  matlab.ui.control.Slider
        Joint3Slider_2Label             matlab.ui.control.Label
        Joint2Slider_2                  matlab.ui.control.Slider
        Joint2Slider_2Label             matlab.ui.control.Label
        Joint1Slider_2                  matlab.ui.control.Slider
        Joint1Slider_2Label             matlab.ui.control.Label
        Joint4Slider                    matlab.ui.control.Slider
        Joint4SliderLabel               matlab.ui.control.Label
        Joint3Slider                    matlab.ui.control.Slider
        Joint3SliderLabel               matlab.ui.control.Label
        Joint2Slider                    matlab.ui.control.Slider
        Joint2SliderLabel               matlab.ui.control.Label
        Joint1Slider                    matlab.ui.control.Slider
        Joint1SliderLabel               matlab.ui.control.Label
        StartTeachButton                matlab.ui.control.Button
        StartDemonstrationButton        matlab.ui.control.Button
        HitButton                       matlab.ui.control.Button
        StandButton                     matlab.ui.control.Button
        CoordinateLabel                 matlab.ui.control.Label
        QueueofCoordinatesSentTextAreaLabel_4  matlab.ui.control.Label
        QueueofCoordinatesSentTextAreaLabel_3  matlab.ui.control.Label
        QueueofCoordinatesSentTextAreaLabel_2  matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        DeleteQueueCoordinateButton     matlab.ui.control.Button
        QueueofCoordinatesSentTextArea  matlab.ui.control.TextArea
        QueueofCoordinatesSentLabel     matlab.ui.control.Label
        SendCartesianCoordinateButton   matlab.ui.control.Button
        ZCoordinateEditField            matlab.ui.control.NumericEditField
        ZCoordinateEditFieldLabel       matlab.ui.control.Label
        YCoordinateEditField            matlab.ui.control.NumericEditField
        YCoordinateEditFieldLabel       matlab.ui.control.Label
        XCoordinateEditField            matlab.ui.control.NumericEditField
        XCoordinateEditFieldLabel       matlab.ui.control.Label
        StartContinueButton             matlab.ui.control.Button
        EmergencyStopButton             matlab.ui.control.Button
    end

    
    properties (Access = public)
        coordinateQueue = []; % Array of cartesian coordinates that the user inputs
        moveAuboDeg = zeros(1,6); % Array element for user to move individual joints of Aubo i5
        moveDobotDeg = zeros(1,3); % Array element for user to move individual joints of Dobot Magician
        logFile; % Variable to store the log file created
        simulationMode = ''; % Variable to determine if blackjack or teach functionality is required

        eStopPressed = false; % Variable to determine if the emergency stop has been pressed
        startPressed = false; % Variable to determine if the start/continue button has been pressed
        endDemonstration = false; % Variable to determine if user wishes to terminate the program
        blackjackAction = 0; % Variable to determine what action of blackjack to undergo
    end

    events
        % Creating events for main function to listen to
        StartButtonPressed;
        EmergencyButtonPressed;
        HitButtonPressed;
        StandButtonPressed;
        CartesianCoordSent;
        DobotJointSent;
        AuboJointSent;
    end
    
    methods (Access = private) 
        function UpdateCoordinateQueueText(app)
            % Updating the text box of the new coordiante within the queue
            numCoordiantes = size(app.coordinateQueue,1); % Getting the amount of cartesian coordiantes inputted
            coordinatesText = ''; % Initialising string to be printed into app text-box

            % Looping through each of the coordinates in the queue and
            % appending to the coordiantesText string
            for i = 1:numCoordiantes
                coordinatesText = [coordinatesText, sprintf('Coordinate %d: X = %.2f, Y = %.2f, Z = %.2f\n', ...
                    i, app.coordinateQueue(i, 1), app.coordinateQueue(i, 2), app.coordinateQueue(i, 3))]; %#ok<AGROW>
            end

            % Updating the textbox to show the new coordinate inputted
            app.QueueofCoordinatesSentTextArea.Value = coordinatesText;            
        end
    end

    methods (Access = public)
        function LoadLogFile(app, L)
            % Loading the log file to the app
            app.logFile = L;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SendCartesianCoordinateButton
        function SendCartesianCoordinateButtonPushed(app, event)
            % Getting the x,y,z values to create a coordiante point
            newCoordinate = [app.XCoordinateEditField.Value, ...
                app.YCoordinateEditField.Value, app.ZCoordinateEditField.Value];

            % Appending the newCoordinate array to coordinateQueue array
            app.coordinateQueue = [app.coordinateQueue; newCoordinate];

            % Updating the coordinate queue text box
            app.UpdateCoordinateQueueText();
        end

        % Button pushed function: DeleteQueueCoordinateButton
        function DeleteQueueCoordinateButtonPushed(app, event)
            % Getting the array element that the user wishes to delete
            coordinateNum = app.EditField.Value;

            % Checking that the entered number is valid coordinate to delete
            if coordinateNum < 1 || coordinateNum > size(app.coordinateQueue,1)
                app.logFile.mlog = {app.logFile.DEBUG,'DeleteQueueCoordinateButtonPushed', ...
                    'Cannot delete a coordinate system outside of queue range'};
                return
            end
            
            % Delete the element at the specified index
            app.coordinateQueue(coordinateNum, :) = [];

            % Updating the coordinate queue text box
            app.UpdateCoordinateQueueText();
        end

        % Button pushed function: EmergencyStopButton
        function EmergencyStopButtonPushed(app, event)
            % Updating e-stop property to opposite of its prior state
            app.eStopPressed = ~app.eStopPressed;

            % if e-stop has been pressed on, start/continue must turn false
            if app.eStopPressed
                % Updating startPressed property of GUI
                app.startPressed = false;
                app.logFile.mlog = {app.logFile.DEBUG,'EmergencyStopButtonPushed', ...
                    'Emergency stop pressed, robot function paused'};
            else
                app.logFile.mlog = {app.logFile.DEBUG,'EmergencyStopButtonPushed', ...
                    'Emergency stop turned off, press continue button when ready'};
            end
        end

        % Button pushed function: StartContinueButton
        function StartContinueButtonPushed(app, event)
            % Checking if the e-stop is off (meaning robot can start/continue)
            if ~app.eStopPressed
                % If e-stop hasn't been pressed, the start property can
                % be turned true - meaning robot can start operation
                app.startPressed = true;
                app.logFile.mlog = {app.logFile.DEBUG,'StartContinueButtonPushed', ...
                    'Robot function started/continued'};
            else
                % Logging that e-stop is on and therefore robots cannot operate
                app.logFile.mlog = {app.logFile.DEBUG,'StartContinueButtonPushed', ...
                    'Cannot start/continue robot operation if e-stop has been pressed'};
            end

            notify(app,'StartButtonPressed');
        end

        % Button pushed function: HitButton
        function HitButtonPushed(app, event)
            % Updating blackjack action property to hit state (i.e. 1)
            app.blackjackAction = 1;
            app.logFile.mlog = {app.logFile.DEBUG,'HitButtonPushed', 'User has asked for a hit'};
        end

        % Button pushed function: StandButton
        function StandButtonPushed(app, event)
            % Updating blackjack action property to stand state (i.e. 2)
            app.blackjackAction = 2;
            app.logFile.mlog = {app.logFile.DEBUG,'StandButtonPushed', 'User has asked to stand'};
        end

        % Button pushed function: StartDemonstrationButton
        function StartDemonstrationButtonPushed(app, event)
            % Updating string to determine what mode gui should be within
            app.simulationMode = 'demonstration';
            app.logFile.mlog = {app.logFile.DEBUG,'StartDemonstrationButtonPushed', 'Program has entered simulation mode'};
        end

        % Button pushed function: StartTeachButton
        function StartTeachButtonPushed(app, event)
            % Updating string to determine what mode gui should be within
            app.simulationMode = 'teach';
            app.logFile.mlog = {app.logFile.DEBUG,'StartTeachButtonPushed', 'Program has entered teach mode'};
        end

        % Value changed function: Joint1Slider
        function Joint1SliderValueChanged(app, event)
            value = app.Joint1Slider.Value; % Getting the value of the slider
            app.moveDobotDeg{1} = value; % Updating the value of the app property
        end

        % Value changed function: Joint2Slider
        function Joint2SliderValueChanged(app, event)
            value = app.Joint2Slider.Value; % Getting the value of the slider
            app.moveDobotDeg{2} = value; % Updating the value of the app property
        end

        % Value changed function: Joint3Slider
        function Joint3SliderValueChanged(app, event)
            value = app.Joint3Slider.Value; % Getting the value of the slider
            app.moveDobotDeg{3} = value; % Updating the value of the app property
        end

        % Value changed function: Joint4Slider
        function Joint4SliderValueChanged(app, event)
            value = app.Joint4Slider.Value; % Getting the value of the slider
            app.moveDobotDeg{4} = value; % Updating the value of the app property
        end

        % Value changed function: Joint1Slider_2
        function Joint1Slider_2ValueChanged(app, event)
            value = app.Joint1Slider_2.Value; % Getting the value of the slider
            app.moveAuboDeg{1} = value; % Updating the value of the app property
        end

        % Value changed function: Joint2Slider_2
        function Joint2Slider_2ValueChanged(app, event)
            value = app.Joint2Slider_2.Value; % Getting the value of the slider
            app.moveAuboDeg{2} = value; % Updating the value of the app property
        end

        % Value changed function: Joint3Slider_2
        function Joint3Slider_2ValueChanged(app, event)
            value = app.Joint3Slider_2.Value; % Getting the value of the slider
            app.moveAuboDeg{3} = value; % Updating the value of the app property
        end

        % Value changed function: Joint4Slider_2
        function Joint4Slider_2ValueChanged(app, event)
            value = app.Joint4Slider_2.Value; % Getting the value of the slider
            app.moveAuboDeg{4} = value; % Updating the value of the app property
        end

        % Value changed function: Joint5Slider_2
        function Joint5Slider_2ValueChanged(app, event)
            value = app.Joint5Slider_2.Value; % Getting the value of the slider
            app.moveAuboDeg{5} = value; % Updating the value of the app property
        end

        % Value changed function: Joint6Slider
        function Joint6SliderValueChanged(app, event)
            value = app.Joint6Slider.Value; % Getting the value of the slider
            app.moveAuboDeg{6} = value; % Updating the value of the app property
        end

        % Value changed function: Joint5Slider
        function Joint5SliderValueChanged(app, event)
            value = app.Joint5Slider.Value; % Getting the value of the slider
            app.moveDobotDeg{5} = value; % Updating the value of the app property
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EmergencyStopButton = uibutton(app.UIFigure, 'push');
            app.EmergencyStopButton.ButtonPushedFcn = createCallbackFcn(app, @EmergencyStopButtonPushed, true);
            app.EmergencyStopButton.Text = 'Emergency Stop';
            app.StartContinueButton = uibutton(app.UIFigure, 'push');
            app.StartContinueButton.ButtonPushedFcn = createCallbackFcn(app, @StartContinueButtonPushed, true);
            app.StartContinueButton.Text = 'Start/Continue';
            app.XCoordinateEditFieldLabel = uilabel(app.UIFigure);
            app.XCoordinateEditFieldLabel.Text = 'X Coordinate';
            app.XCoordinateEditField = uieditfield(app.UIFigure, 'numeric');
            app.YCoordinateEditFieldLabel = uilabel(app.UIFigure);
            app.YCoordinateEditFieldLabel.Text = 'Y Coordinate';
            app.YCoordinateEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZCoordinateEditFieldLabel = uilabel(app.UIFigure);
            app.ZCoordinateEditFieldLabel.Text = 'Z Coordinate';
            app.ZCoordinateEditField = uieditfield(app.UIFigure, 'numeric');
            app.SendCartesianCoordinateButton = uibutton(app.UIFigure, 'push');
            app.SendCartesianCoordinateButton.ButtonPushedFcn = createCallbackFcn(app, @SendCartesianCoordinateButtonPushed, true);
            app.SendCartesianCoordinateButton.Text = 'Send Cartesian Coordinate';
            app.QueueofCoordinatesSentLabel = uilabel(app.UIFigure);
            app.QueueofCoordinatesSentLabel.Text = 'Queue of Coordinates Sent:';
            app.QueueofCoordinatesSentTextArea = uitextarea(app.UIFigure);
            app.DeleteQueueCoordinateButton = uibutton(app.UIFigure, 'push');
            app.DeleteQueueCoordinateButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteQueueCoordinateButtonPushed, true);
            app.DeleteQueueCoordinateButton.Text = 'Delete Queue Coordinate';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.QueueofCoordinatesSentTextAreaLabel_2 = uilabel(app.UIFigure);
            app.QueueofCoordinatesSentTextAreaLabel_2.Text = {'Send End-Effector to '; 'Cartesian Coordinate:'};
            app.QueueofCoordinatesSentTextAreaLabel_3 = uilabel(app.UIFigure);
            app.QueueofCoordinatesSentTextAreaLabel_3.Text = 'Dobot Magician Joint Movement:';
            app.QueueofCoordinatesSentTextAreaLabel_4 = uilabel(app.UIFigure);
            app.QueueofCoordinatesSentTextAreaLabel_4.Text = 'Aubo i5 Joint Movement:';
            app.CoordinateLabel = uilabel(app.UIFigure);
            app.CoordinateLabel.Text = 'Coordinate:';
            app.StandButton = uibutton(app.UIFigure, 'push');
            app.StandButton.ButtonPushedFcn = createCallbackFcn(app, @StandButtonPushed, true);
            app.StandButton.Text = 'Stand';
            app.HitButton = uibutton(app.UIFigure, 'push');
            app.HitButton.ButtonPushedFcn = createCallbackFcn(app, @HitButtonPushed, true);
            app.HitButton.Text = 'Hit';
            app.StartDemonstrationButton = uibutton(app.UIFigure, 'push');
            app.StartDemonstrationButton.ButtonPushedFcn = createCallbackFcn(app, @StartDemonstrationButtonPushed, true);
            app.StartDemonstrationButton.Text = 'Start Demonstration';
            app.StartTeachButton = uibutton(app.UIFigure, 'push');
            app.StartTeachButton.ButtonPushedFcn = createCallbackFcn(app, @StartTeachButtonPushed, true);
            app.StartTeachButton.Text = 'Start Teach';
            app.Joint1SliderLabel = uilabel(app.UIFigure);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.Limits = [-135 135];
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @Joint1SliderValueChanged, true);
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint2Slider.Limits = [5 80];
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @Joint2SliderValueChanged, true);
            app.Joint2Slider.Value = 45;
            app.Joint3SliderLabel = uilabel(app.UIFigure);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.UIFigure);
            app.Joint3Slider.Limits = [-5 80];
            app.Joint3Slider.ValueChangedFcn = createCallbackFcn(app, @Joint3SliderValueChanged, true);
            app.Joint3Slider.Value = 45;
            app.Joint4SliderLabel = uilabel(app.UIFigure);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.UIFigure);
            app.Joint4Slider.Limits = [-180 180];
            app.Joint4Slider.ValueChangedFcn = createCallbackFcn(app, @Joint4SliderValueChanged, true);
            app.Joint1Slider_2Label = uilabel(app.UIFigure);
            app.Joint1Slider_2Label.Text = 'Joint 1';
            app.Joint1Slider_2 = uislider(app.UIFigure);
            app.Joint1Slider_2.Limits = [-175 175];
            app.Joint1Slider_2.ValueChangedFcn = createCallbackFcn(app, @Joint1Slider_2ValueChanged, true);
            app.Joint2Slider_2Label = uilabel(app.UIFigure);
            app.Joint2Slider_2Label.Text = 'Joint 2';
            app.Joint2Slider_2 = uislider(app.UIFigure);
            app.Joint2Slider_2.Limits = [-175 175];
            app.Joint2Slider_2.ValueChangedFcn = createCallbackFcn(app, @Joint2Slider_2ValueChanged, true);
            app.Joint3Slider_2Label = uilabel(app.UIFigure);
            app.Joint3Slider_2Label.Text = 'Joint 3';
            app.Joint3Slider_2 = uislider(app.UIFigure);
            app.Joint3Slider_2.Limits = [-175 175];
            app.Joint3Slider_2.ValueChangedFcn = createCallbackFcn(app, @Joint3Slider_2ValueChanged, true);
            app.Joint4Slider_2Label = uilabel(app.UIFigure);
            app.Joint4Slider_2Label.Text = 'Joint 4';
            app.Joint4Slider_2 = uislider(app.UIFigure);
            app.Joint4Slider_2.Limits = [-175 175];
            app.Joint4Slider_2.ValueChangedFcn = createCallbackFcn(app, @Joint4Slider_2ValueChanged, true);
            app.Joint5Slider_2Label = uilabel(app.UIFigure);
            app.Joint5Slider_2Label.Text = 'Joint 5';
            app.Joint5Slider_2 = uislider(app.UIFigure);
            app.Joint5Slider_2.Limits = [-175 175];
            app.Joint5Slider_2.ValueChangedFcn = createCallbackFcn(app, @Joint5Slider_2ValueChanged, true);
            app.Joint6SliderLabel = uilabel(app.UIFigure);
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.Joint6Slider = uislider(app.UIFigure);
            app.Joint6Slider.Limits = [-175 175];
            app.Joint6Slider.ValueChangedFcn = createCallbackFcn(app, @Joint6SliderValueChanged, true);
            app.Joint5SliderLabel = uilabel(app.UIFigure);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.UIFigure);
            app.Joint5Slider.Limits = [-85 85];
            app.Joint5Slider.ValueChangedFcn = createCallbackFcn(app, @Joint5SliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end